classdef Lloyd_App_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        NumberofAgentsEditFieldLabel    matlab.ui.control.Label
        NumberofAgentsEditField         matlab.ui.control.NumericEditField
        SideLengthEditFieldLabel        matlab.ui.control.Label
        SideLengthEditField             matlab.ui.control.NumericEditField
        PartitionNumberEditFieldLabel   matlab.ui.control.Label
        PartitionNumberEditField        matlab.ui.control.NumericEditField
        VelocityDropDownLabel           matlab.ui.control.Label
        VelocityDropDown                matlab.ui.control.DropDown
        MaximumVelocityEditFieldLabel   matlab.ui.control.Label
        MaximumVelocityEditField        matlab.ui.control.NumericEditField
        DensityTypeDropDownLabel        matlab.ui.control.Label
        DensityTypeDropDown             matlab.ui.control.DropDown
        DensityTimeVarianceDropDownLabel  matlab.ui.control.Label
        DensityTimeVarianceDropDown     matlab.ui.control.DropDown
        DensityFunctionEditFieldLabel   matlab.ui.control.Label
        DensityFunctionEditField        matlab.ui.control.EditField
        DelayEditFieldLabel             matlab.ui.control.Label
        DelayEditField                  matlab.ui.control.NumericEditField
        FinalIterationEditFieldLabel    matlab.ui.control.Label
        FinalIterationEditField         matlab.ui.control.NumericEditField
        AlgorithmDropDownLabel          matlab.ui.control.Label
        AlgorithmDropDown               matlab.ui.control.DropDown
        PlayButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        RandomizePositionsButton        matlab.ui.control.Button
        NameofCostFunction2EditFieldLabel  matlab.ui.control.Label
        NameofCostFunction2EditField    matlab.ui.control.EditField
        CostFunction2YAxisEditFieldLabel  matlab.ui.control.Label
        CostFunction2YAxisEditField     matlab.ui.control.EditField
        CurrentIterationEditFieldLabel  matlab.ui.control.Label
        CurrentIterationEditField       matlab.ui.control.NumericEditField
        VelocityEditFieldLabel          matlab.ui.control.Label
        VelocityEditField               matlab.ui.control.NumericEditField
        DensityMatrixNameEditFieldLabel  matlab.ui.control.Label
        DensityMatrixNameEditField      matlab.ui.control.EditField
        OrderFrequencyEditFieldLabel    matlab.ui.control.Label
        OrderFrequencyEditField         matlab.ui.control.NumericEditField
        RandomDelayCheckBox             matlab.ui.control.CheckBox
        UITable2                        matlab.ui.control.Table
    end


    methods (Access = private)
        
   %%%%%%%%%%%%%%%%%%%%%%%%% THESE ARE LOCAL PLOTTING FUNCTIONS %%%%%%%%%%%%%%%%%%%%%%%
    
    function [] = circle_Maker(app, x_centre,y_centre,r_o)
    % Draws a circle of specified radius around point (x_centre,y_centre)
    
    theta = linspace(0,2*pi,100);
    x = r_o.*cos(theta) + x_centre;
    y = r_o.*sin(theta) + y_centre;
    
    plot(app.UIAxes,x,y,'magenta')
    end
    
    function [] = Plot_Assigned_Points(app, Agent_Points, Number_of_Agents,...
        colours_energy,Agent_Positions,sides)
    
        for i = 1:Number_of_Agents
            hold(app.UIAxes3, 'on')
            scatter(app.UIAxes3, Agent_Points{1,i}(:,1),Agent_Points{1,i}(:,2),...
                'MarkerEdgeColor',colours_energy(i,:));
            hold(app.UIAxes3, 'on')
            scatter(app.UIAxes3, Agent_Positions(i,1),Agent_Positions(i,2),50,...
                colours_energy(i,:),'filled','diamond')
            label_agent_index = num2str(i);
            t.FontSize = 15;
            text(app.UIAxes3, Agent_Positions(i,1) + sides/65, Agent_Positions(i,2) + sides/65,...
                        label_agent_index);
            app.UIAxes3.XLim = [0 app.SideLengthEditField.Value];
            app.UIAxes3.YLim = [0 app.SideLengthEditField.Value];
        end
    end 
    
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% END OF LOCAL FUNCTIONS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    end 


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%Generates random data upon startup (3rd column is just the number of the agent)
            
%Default config
app.NumberofAgentsEditField.Value = 5;
app.SideLengthEditField.Value = 5;
app.PartitionNumberEditField.Value = 1;
app.VelocityDropDown.Value = 'Constant Velocity';
app.MaximumVelocityEditField.Value = 1;
app.VelocityEditField.Value = 0;
app.DensityTypeDropDown.Value = 'Matrix';
app.DensityTimeVarianceDropDown.Value = 'Dynamic';
app.DensityFunctionEditField.Value = '1';
app.DelayEditField.Value = 1;
app.FinalIterationEditField.Value= 10;
app.AlgorithmDropDown.Value = 'Regular';
app.DensityMatrixNameEditField.Value = 'initDensity';
app.OrderFrequencyEditField.Value = 1;
app.AlgorithmDropDown.Value = 'Regular';
app.RandomDelayCheckBox.Value = 0;


n = app.NumberofAgentsEditField.Value;
L = app.SideLengthEditField.Value;

Agent_Positions = zeros(n,3);

for i = 1:n
    Agent_Positions(i,1) = randi(L-1);
    Agent_Positions(i,2) = randi(L-1);
    Agent_Positions(i,3) = i;
end

app.UITable.Data = Agent_Positions;
        end

        % Button pushed function: RandomizePositionsButton
        function RandomizePositionsButtonPushed(app, event)
%Randomizes data if the randomize button is pushed
            
n = app.NumberofAgentsEditField.Value;
L = app.SideLengthEditField.Value;

Agent_Positions = zeros(n,2);

for i = 1:n
    Agent_Positions(i,1) = rand(1,1)*(L);
    Agent_Positions(i,2) = rand(1,1)*(L);
end

app.UITable.Data(:,1:2) = Agent_Positions;
        end

        % Callback function
        function StopButtonPushed(app, event)
        app.CurrentIterationEditField.Value = app.FinalIterationEditField.Value+1;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
%This is the main body of the code. It executes Lloyd's algorithm when the start button is pressed. 
close('all')            

%NOTE: SOME DOCUMENTATION MAY REFER TO THIS FUNCTION AS LLOYDSPLAY
%Get all of the input values that we'll need
sides = app.SideLengthEditField.Value;
Partition_Number = app.PartitionNumberEditField.Value;
Agent_Positions = app.UITable.Data(:,1:2);
Number_of_Agents = app.NumberofAgentsEditField.Value;
max_velocity = app.MaximumVelocityEditField.Value;
Density_Type = app.DensityTypeDropDown.Value;
Velocity_Type = app.VelocityDropDown.Value;
Delay_Type = app.RandomDelayCheckBox.Value;
final_iteration = app.FinalIterationEditField.Value;
Density_variation_type = app.DensityTimeVarianceDropDown.Value;
delay = app.DelayEditField.Value;
algorithm_type = app.AlgorithmDropDown.Value;
app.CurrentIterationEditField.Value = 1;
arena_dimensions = app.SideLengthEditField.Value;
order_frequency = app.OrderFrequencyEditField.Value;
% order_queue is a sides*sides matrix where each location has the number of orders for that grid space
order_queue = zeros(sides,sides);
total_orders = zeros(sides,sides);
% default values so that the whole app still runs
r_o = 2*sides;
r_c = 2*sides;

for i = 1:Number_of_Agents
    colours_energy(i,:) = rand(1,3); % Generate colours for energy example
end

%Assign numerical values to all of the decisions we make in the app
if strcmpi(Density_Type, 'Matrix')
    Density_Type = 1;
else 
    Density_Type = 2;
end 

if strcmpi(Velocity_Type, 'Constant Velocity')
    Velocity_Type = 1;
else 
    Velocity_Type = 2;
end 

if strcmpi(algorithm_type, 'Regular')
    algorithm_type = 1;
else 
    algorithm_type = 2;
end 

if strcmpi(Density_variation_type, 'Static')
    Density_variation_type = 1;
else 
    Density_variation_type = 2;
end 


if Velocity_Type == 1
    MOVEMENTSCALE = 1;
    velocity = app.VelocityEditField.Value;
else
    MOVEMENTSCALE = app.VelocityEditField.Value;
    velocity = 1;
end

History = [];
for i = 1 : Number_of_Agents
    History(1:1,(2*i-1):2*i) = Agent_Positions(i,1:2);
end

if Density_Type == 1
    % If Density is from a pre-made matrix
    % Must ensure dimensions of initial matrix are equal to those of sides
    Density = load(app.DensityMatrixNameEditField.Value, '-mat');
elseif Density_Type == 2
    % If density is random
    % Note: hard-coded value always gives random float between 0 and 5
    % for each location in grid
    Density = 5*rand(sides);
end

% If a random delay is selected, the max delay is the value in the delay input field
if Delay_Type == 1
    delay_max = delay;
end

syms x y z
% yy, xx used in contour plot
[yy,xx] = meshgrid(linspace(0,sides,sides*Partition_Number)); %If you want contours, then you need variable density!!
Agent_Positions = Fixing_Starting_Positions(Agent_Positions,Partition_Number,sides);

j = 1;
Percent_Covered = [];
distance_travelled = zeros(1);
if Density_Type == 1
    Density = Density.D; %convert struct to array (variable must be named D originally)
end    
% Total orders is intialized as density
total_orders = Density;
% Get initial total mass
Total_Mass = sum(sum(Density));

% %% Energy Example (ONLY WORKS IF CUSTOM ALGORITHM IS SELECTED -> SEE DOCUMENTATION)
E = zeros(1,Number_of_Agents);
for i = 1:Number_of_Agents
    E(i) = 100; % Assume agents have 100% of energy at the start
end
E(2) = 40; %Just to make it different, allow other agents to have less energy
E(3) = 20;

count = 1;
Density_cumulative = Density;
target_region_mass = Total_Mass/Number_of_Agents;

% Initialize Centroids to agent positions
Centroids = Agent_Positions;


%----------------------------------------- Loop -----------------------------------------------------%

while (count <= final_iteration && app.CurrentIterationEditField.Value >0)


% Thought: on first iteration, there is no way to determine which agents can communicate
% Communication logic must be modified as follows:
[coms, com_mat] = communication_fun(Agent_Positions,r_c,Number_of_Agents,algorithm_type);
count = count + 1;

% REMOVE COMMUNICATION PLOT, REPLACE WITH A MORE USEFUL PLOT
%% Plot Communication Graph %%    
% We first clear the old plots and make the necessary amount of colours:
cla(app.UIAxes)
cla(app.UIAxes2)
cla(app.UIAxes3)
cla(app.UIAxes4)
n_connected_subgraphs = size(coms,2);
close('all')    
    if count == 2
        for i = 1:n_connected_subgraphs
            colours(i,:) = rand(1,3);
        end
    end     
    
        for i = 1:size(Agent_Positions,1)
            scatter(app.UIAxes4,Agent_Positions(:,1),Agent_Positions(:,2));
            label_agent_index = num2str(i);
            text(app.UIAxes4, Agent_Positions(i,1) + sides/65, Agent_Positions(i,2) + sides/65,...
                label_agent_index);
            hold(app.UIAxes4, 'on')
        end
        for i = 1:n_connected_subgraphs
            for j = 1:size(coms{1,i},1)
                for k = 1:size(coms{1,i},1)
                    if com_mat(coms{1,i}(j,1),coms{1,i}(k,1)) == 1 
                            x1 = Agent_Positions(coms{1,i}(j,1),1);
                            y1 = Agent_Positions(coms{1,i}(j,1),2);
                            x2 = Agent_Positions(coms{1,i}(k,1),1);
                            y2 = Agent_Positions(coms{1,i}(k,1),2);
                            hold(app.UIAxes4, 'on')
                            plot(app.UIAxes4,[x1 x2],[y1 y2],'Color',colours(i,:));
                            hold(app.UIAxes4, 'on')
                    end
                end
            end
        end 
%% End of Communication Plot %%
        
% Points are assigned based on Centroids of existing regions
% Then centroids are updated based on the newly assigned agent points
Agent_Points = assign_points(Centroids,sides,target_region_mass,Density);
% Debug print
% for i = 1:size(Agent_Points,2)
%     disp(['Agent ' sprintf('%d',i)])
%     for j = 1:size(Agent_Points{1,i})
%         disp(Agent_Points{1,i}(j,:))
%     end
% end
Mass = mass_calc(Agent_Points, Density, Number_of_Agents);

%% UPDATE UIAXES2
% Thought: Replace covered mass plot with total mass per region plot
covered_mass = covered_mass_fun(Agent_Positions,Density,r_o,...
    Partition_Number,Number_of_Agents,algorithm_type);
% Calculation done here
if covered_mass >= Total_Mass
    Percent_Covered = horzcat(Percent_Covered, 100);
else
    Percent_Covered = horzcat(Percent_Covered, 100*covered_mass/Total_Mass);
end
% Plotting and updating figure done here
independent = 1:(count-1); % for plotting the coverage figure
yyaxis(app.UIAxes2, 'left')
plot(app.UIAxes2,independent, Percent_Covered, 'b');
hold(app.UIAxes2, 'on')
%% END UPDATE UIAXES2

% Calculate centroids
Centroids = centroid_finder(Agent_Points,Mass,Density,...
    Number_of_Agents,Agent_Positions);

% Before we move the agents, we need to sort the order_queue for all agents
[agent_orders, order_queue] = sort_orders(Agent_Points, Agent_Positions, order_queue);

%% UPDATE ORDER QUEUE TABLE
% Display order_queue by agent
last_row = 1;
app.UITable2.Data = [];
for agent_num = 1:Number_of_Agents
    % sz is # of distinct order locations for a given agent
    sz = size(agent_orders{1,agent_num},1);
    % For when I have to decipher this enigma line of code:
    % "The rows last_row to last_row + sz in the UITable equals the sz by 3 matrix
    % where the first 2 columns are the x,y point and the 3rd column is the agent num"
    if sz > 0
        app.UITable2.Data(last_row:last_row+sz-1,:) = horzcat(agent_orders{1,agent_num}, agent_num*ones(sz,1));
        last_row = last_row + sz;
    end
end
% If there are no items in the order queue, clear the table
% if last_row == 1
%     app.UITable2.Data = [];
% end
% Might need to consider removing the data 
%% END UPDATE ORDER QUEUE TABLE


% After finding centroids, update home locations
% Agents will then move:
% If there is an order in the agent's region, go get that
% IF there are no orders, and the agent isn't currently retrieving an item,
% Then go to the home location
[Agent_Positions,distance_travelled,E] = move_agents(Agent_Positions,...
    Centroids,distance_travelled,Velocity_Type,velocity,...
    max_velocity,MOVEMENTSCALE,algorithm_type,E);

%% UPDATE EXCEL VARIABLES
for k = 1 : Number_of_Agents % for saving to Excel
    Latest_History(1:1,(2*k-1):2*k) = Agent_Positions(k,1:2);
end
History = vertcat(History,Latest_History); % for saving to Excel
%% END UPDATE EXCEL VARIABLES


%% UPDATE UIAXES PLOTS
for i = 1:Number_of_Agents % Plot Agents and centroids
    % Begin plotting
    hold(app.UIAxes, 'on')
    % Scatter plot of Agent_Positions on UIAxes in blue filled dots
    scatter(app.UIAxes,Agent_Positions(i,1),...
        Agent_Positions(i,2),90,'blue','filled');
    % Reset bounds/limits of plot, in case user zoomed in/out
    app.UIAxes.XLim = [0 sides];
    app.UIAxes.YLim = [0 sides];
    % Label each blue dot with an agent number
    label_agent_index = num2str(i);
    t.FontSize = 20;
    % Plot the label on UIAxes, 1/65 the side length of the arena away from agent's blue dot
    text(app.UIAxes, Agent_Positions(i,1) + sides/65, Agent_Positions(i,2) + sides/65,...
        label_agent_index);
    hold(app.UIAxes, 'on')
    % Plot centroids as a scatter plot on UIAxes too
    scatter(app.UIAxes,Centroids(i,1),Centroids(i,2),50,'red','filled');  
    hold(app.UIAxes, 'on')
    
    % This code plots the radius of observation around agents, which we don't need
%     theta = linspace(0,2*pi,100);
%     x = r_o.*cos(theta) + Agent_Positions(i,1);
%     y = r_o.*sin(theta) + Agent_Positions(i,2);
%     
%     plot(app.UIAxes,x,y,'magenta')
%     
%     circle_Maker(app, Agent_Positions(i,1), Agent_Positions(i,2),r_o); 
end

% Update the table displayng agent positions
app.UITable.Data(:,1:2) = Agent_Positions;    
    
hold(app.UIAxes, 'on')
% Draw a Voronoi plot around the centroids
% NOTE: This Voronoi plot used to be centered around agent positions
% But makes more sense to use centroids in our implementation
voronoi(app.UIAxes,Centroids(:,1),Centroids(:,2));
hold(app.UIAxes, 'on')
% Draw the contours determined by the density on UIAxes
% Note: Should look into exactly how the contour plot works
% and figure out what the returned contour_plot is for
[contour_plot, h] = contour(app.UIAxes,xx,yy,Density,4); % h is used for line width
set(h,'linewidth',2);
%% END UPDATE UIAXES PLOTS

%% UPDATE UIAXES2 COST FUNCTION
str = app.NameofCostFunction2EditField.Value;
stra = 'Coverage and ';
str = [stra str];
independent = 1:count; % for plotting cost function 2 (distance travelled)
yyaxis(app.UIAxes2, 'right') 
ylabel(app.UIAxes2, app.CostFunction2YAxisEditField.Value);
title(app.UIAxes2, str)
plot(app.UIAxes2,independent, distance_travelled, 'r');
hold(app.UIAxes2, 'off')
%% END UPDATE UIAXES2 COST FUNCTION

%% UPDATE UIAXES3
% Should try to update this to display assigned points better
hold(app.UIAxes3, 'on')
Plot_Assigned_Points(app, Agent_Points,Number_of_Agents,colours_energy,...
    Agent_Positions,sides);
hold(app.UIAxes3, 'on')
pause(0.1);
%% END UPDATE UIAXES3

%% UPDATE DENSITY AND MASS
if (count-1 <= final_iteration) || (final_iteration <= 0)
    cla(app.UIAxes)
    clf(app.UIAxes);
    if Density_variation_type == 2
        if Delay_Type == 1
            delay = randi([1 delay_max]);
        end
        [total_orders, order_queue] = iteration_variation_fun(total_orders,order_queue,count-1,delay,...
            order_frequency);
        Density = total_orders / count;
        Total_Mass = sum(sum(Density));
        target_region_mass = Total_Mass/Number_of_Agents;
        Density_cumulative = vertcat(Density_cumulative, Density);
    end
end 
%% END UPDATE DENSITY AND MASS

% Increment loop counter
j = j + 1;
app.CurrentIterationEditField.Value = count;

end
% ------------------------------------ END LOOP ----------------------------------------

% Close plots and save data to .csv files in tabular format
close all; 
History2 = History*(arena_dimensions/sides); 
% delete agentData.csv;
% disp('Saving History:')
% disp(History)
writetable(table(History),'agentData1.csv');
% disp('Saving History2:')
% disp(History2)
writetable(table(History2),'agentData2.csv');
% disp('Saving % Covered:')
% disp(Percent_Covered)
writetable(table(Percent_Covered'),'agentData3.csv');
% disp('Saving Distance Travelled:')
% disp(distance_travelled)
writetable(table(distance_travelled'),'agentData4.csv');
% disp('Saving Cumulative Density:')
% disp(Density_cumulative)
writetable(table(Density_cumulative), 'agentData5.csv');

Extra_info_matrix = zeros(1,3);
Extra_info_matrix(1,1) = r_o;
Extra_info_matrix(1,2) = r_c;
Extra_info_matrix(1,3) = Density_variation_type;
% disp('Saving Extra info:')
% disp(Extra_info_matrix)
writetable(table(Extra_info_matrix),'agentData6.csv');
H = History;
save('agentData.mat','H');

   
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %this resets everything if the reset button is pushed
            
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            app.CurrentIterationEditField.Value = 0;
            n = app.NumberofAgentsEditField.Value;
            L = app.SideLengthEditField.Value;
            
            Agent_Positions = zeros(n,3);
            
            for i = 1:n
                Agent_Positions(i,1) = rand(1,1)*(L);
                Agent_Positions(i,2) = rand(1,1)*(L);
                Agent_Positions(i,3) = i;
            end
            
            app.UITable.Data = Agent_Positions;
            app.UITable2.Data = [];
            
        end

        % Value changed function: NumberofAgentsEditField
        function NumberofAgentsEditFieldValueChanged(app, event)
            %This section re-randomizes the data if the number of agents is changed
            
            n = app.NumberofAgentsEditField.Value;
            L = app.SideLengthEditField.Value;
            
            Agent_Positions = zeros(n,3);
            
            for i = 1:n
                Agent_Positions(i,1) = rand(1,1)*(L);
                Agent_Positions(i,2) = rand(1,1)*(L);
                Agent_Positions(i,3) = i;
            end
            
            app.UITable.Data = Agent_Positions;
            
        end

        % Value changed function: SideLengthEditField
        function SideLengthEditFieldValueChanged(app, event)
       %This section re-randomizes the data if the side length is changed
            
         n = app.NumberofAgentsEditField.Value;
         L = app.SideLengthEditField.Value;

        Agent_Positions = zeros(n,2);

        for i = 1:n
            Agent_Positions(i,1) = rand(1,1)*(L);
            Agent_Positions(i,2) = rand(1,1)*(L);
        end

        app.UITable.Data(:,1:2) = Agent_Positions;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Arena')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Coverage')
            xlabel(app.UIAxes2, 'Iteration Number')
            ylabel(app.UIAxes2, 'Percent Covered')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Assigned Points')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Communication Graph')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.NumberofAgentsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofAgentsEditFieldLabel.Text = 'Number of Agents';
            app.NumberofAgentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofAgentsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofAgentsEditFieldValueChanged, true);
            app.NumberofAgentsEditField.Value = 10;
            app.SideLengthEditFieldLabel = uilabel(app.UIFigure);
            app.SideLengthEditFieldLabel.Text = 'Side Length';
            app.SideLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SideLengthEditField.ValueChangedFcn = createCallbackFcn(app, @SideLengthEditFieldValueChanged, true);
            app.SideLengthEditField.Value = 5;
            app.PartitionNumberEditFieldLabel = uilabel(app.UIFigure);
            app.PartitionNumberEditFieldLabel.Text = 'Partition Number';
            app.PartitionNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.PartitionNumberEditField.Value = 10;
            app.VelocityDropDownLabel = uilabel(app.UIFigure);
            app.VelocityDropDownLabel.Text = 'Velocity';
            app.VelocityDropDown = uidropdown(app.UIFigure);
            app.VelocityDropDown.Items = {'Constant Velocity', 'Proportional Velocity'};
            app.VelocityDropDown.Value = 'Constant Velocity';
            app.MaximumVelocityEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumVelocityEditFieldLabel.Text = 'Maximum Velocity';
            app.MaximumVelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumVelocityEditField.Value = 1;
            app.DensityTypeDropDownLabel = uilabel(app.UIFigure);
            app.DensityTypeDropDownLabel.Text = 'Density Type';
            app.DensityTypeDropDown = uidropdown(app.UIFigure);
            app.DensityTypeDropDown.Items = {'Matrix', 'Random'};
            app.DensityTypeDropDown.Value = 'Matrix';
            app.DensityTimeVarianceDropDownLabel = uilabel(app.UIFigure);
            app.DensityTimeVarianceDropDownLabel.Text = 'Density Time-Variance';
            app.DensityTimeVarianceDropDown = uidropdown(app.UIFigure);
            app.DensityTimeVarianceDropDown.Items = {'Static', 'Dynamic'};
            app.DensityTimeVarianceDropDown.Value = 'Static';
            app.DensityFunctionEditFieldLabel = uilabel(app.UIFigure);
            app.DensityFunctionEditFieldLabel.Text = 'Density Function';
            app.DensityFunctionEditField = uieditfield(app.UIFigure, 'text');
            app.DensityFunctionEditField.Value = '1';
            app.DelayEditFieldLabel = uilabel(app.UIFigure);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.UIFigure, 'numeric');
            app.DelayEditField.Value = 5;
            app.FinalIterationEditFieldLabel = uilabel(app.UIFigure);
            app.FinalIterationEditFieldLabel.Text = 'Final Iteration';
            app.FinalIterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinalIterationEditField.Value = 50;
            app.AlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.UIFigure);
            app.AlgorithmDropDown.Items = {'Regular', 'Custom'};
            app.AlgorithmDropDown.Value = 'Regular';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'; '(Agent #)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true false];
            app.RandomizePositionsButton = uibutton(app.UIFigure, 'push');
            app.RandomizePositionsButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizePositionsButtonPushed, true);
            app.RandomizePositionsButton.Text = 'Randomize Positions';
            app.NameofCostFunction2EditFieldLabel = uilabel(app.UIFigure);
            app.NameofCostFunction2EditFieldLabel.Text = 'Name of Cost Function 2';
            app.NameofCostFunction2EditField = uieditfield(app.UIFigure, 'text');
            app.NameofCostFunction2EditField.Value = 'Energy';
            app.CostFunction2YAxisEditFieldLabel = uilabel(app.UIFigure);
            app.CostFunction2YAxisEditFieldLabel.Text = 'Cost Function 2 Y-Axis';
            app.CostFunction2YAxisEditField = uieditfield(app.UIFigure, 'text');
            app.CostFunction2YAxisEditField.Value = 'Distance Travelled';
            app.CurrentIterationEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentIterationEditFieldLabel.Text = 'Current Iteration';
            app.CurrentIterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentIterationEditField.Value = 1;
            app.VelocityEditFieldLabel = uilabel(app.UIFigure);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.VelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.DensityMatrixNameEditFieldLabel = uilabel(app.UIFigure);
            app.DensityMatrixNameEditFieldLabel.Text = 'Density Matrix Name';
            app.DensityMatrixNameEditField = uieditfield(app.UIFigure, 'text');
            app.OrderFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.OrderFrequencyEditFieldLabel.Text = 'Order Frequency';
            app.OrderFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrderFrequencyEditField.Value = 1;
            app.RandomDelayCheckBox = uicheckbox(app.UIFigure);
            app.RandomDelayCheckBox.Text = 'Random Delay?';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'X'; 'Y'; 'Agent #'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false false false];
        end
    end

    methods (Access = public)

        % Construct app
        function app = Lloyd_App_final

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end